classdef banda_base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_4                         matlab.ui.container.Panel
        PulseshapingFilterCheckBox_2    matlab.ui.control.CheckBox
        CodificaodelinhaDropDown_2      matlab.ui.control.DropDown
        CodificaodelinhaDropDown_2Label  matlab.ui.control.Label
        GeralsinalButton                matlab.ui.control.Button
        SairButton                      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        CodificaoconvolucionalSwitch    matlab.ui.control.Switch
        CodificaoconvolucionalSwitchLabel  matlab.ui.control.Label
        Coeficientespolinmio2EditField  matlab.ui.control.EditField
        Coeficientespolinmio2EditFieldLabel  matlab.ui.control.Label
        Coeficientespolinmio1EditField  matlab.ui.control.EditField
        Coeficientespolinmio1EditFieldLabel  matlab.ui.control.Label
    end

    % Properties gerais do sistema banda-base (metadados)
    properties (Access = private)
        convolucional   logical = false
        pulse_shaping   logical = false
        NRZ_levels      uint8 = 1
        g1              (1,:)char = '11011'
        g2              (1,:)char = '10101'
    end
    
    % Sinais de DEMONSTRAÇÃO!
    properties (Access = private)
        demo_data       (1,:) uint8 = [0 1 1 1 0 0 0 1 1 0 1 0 1 1 1 1]
        demo_bit_seq    (1,:) uint8 
        coeffs_i        (1,:) double = []
        coeffs_q        (1,:) double = []
        wave_i          (1,:) double = []
        wave_q          (1,:) double = []
        t               (1,:) double = []
    end

    properties (Access = public)
        done_flag   logical = false
    end
    
    methods (Access = public)
        
        function [t, wv_i, wv_q] = toModulation(app)
            t = app.t;
            wv_i = app.wave_i;
            wv_q = app.wave_q;
        end

        function setDemoData(app, demoData)
            if ~isempty(demoData)
                app.demo_data = demoData;
            end
        end

        function [conv, pulse, N_levels, g1, g2] = getParams(app)
            conv = app.convolucional;
            pulse = app.pulse_shaping;
            N_levels = app.NRZ_levels;
            g1 = app.g1;
            g2 = app.g2;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeralsinalButton
        function GeralsinalButtonPushed(app, event)
            tmp_g1 = app.Coeficientespolinmio1EditField.Value;
            tmp_g2 = app.Coeficientespolinmio2EditField.Value;
            
            sz1 = size(tmp_g1);
            sz2 = size(tmp_g2);

            if ~isequal(sz1, sz2) || sz1(2) > 7 || sz1(2) < 3 || ...
                any(tmp_g1 ~= '0' & tmp_g1 ~= '1') || any(tmp_g2 ~= '0' & tmp_g2 ~= '1')
                app.g1 = '11011';
                app.g2 = '10101';
            else 
                app.g1 = tmp_g1;
                app.g2 = tmp_g2;
            end
            app.PulseshapingFilterCheckBox_2.Enable = "on";

            if app.CodificaoconvolucionalSwitch.Value == "On"
                app.demo_bit_seq = conv_encoder(app.demo_data, app.g1, app.g2);
                app.convolucional = true;
            else
                app.demo_bit_seq = app.demo_data;
                app.convolucional = false;
            end
            cla(app.UIAxes); 
            cla(app.UIAxes2);
            switch app.NRZ_levels
                case 1
                    [app.t, app.coeffs_i, app.wave_i] = NRZ_polar_BPSK(app.demo_bit_seq);
                    app.wave_q = [];
                    app.coeffs_q = [];
                    if app.pulse_shaping
                        [app.wave_i, ~, ~] = pulse_shaping_filter(app.coeffs_i, 1);
                    end
                    plot(app.UIAxes, app.t, app.wave_i);
                    cla(app.UIAxes2);
                case 2
                    [app.t, app.coeffs_i, app.coeffs_q, app.wave_i, app.wave_q] = NRZ_polar_QAM(app.demo_bit_seq, 2);
                    if app.pulse_shaping
                        [app.wave_i, ~, ~] = pulse_shaping_filter(app.coeffs_i, 2);
                        [app.wave_q, ~, ~] = pulse_shaping_filter(app.coeffs_q, 2);
                    end
                    plot(app.UIAxes, app.t, app.wave_i);
                    plot(app.UIAxes2, app.t, app.wave_q);
                case 4
                    [app.t, app.coeffs_i, app.coeffs_q, app.wave_i, app.wave_q] = NRZ_polar_QAM(app.demo_bit_seq, 4);
                    if app.pulse_shaping
                        [app.wave_i, ~, ~] = pulse_shaping_filter(app.coeffs_i, 4);
                        [app.wave_q, ~, ~] = pulse_shaping_filter(app.coeffs_q, 4);
                    end
                    plot(app.UIAxes, app.t, app.wave_i);
                    plot(app.UIAxes2, app.t, app.wave_q);
                case 8
                    [app.t, app.coeffs_i, app.coeffs_q, app.wave_i, app.wave_q] = NRZ_polar_QAM(app.demo_bit_seq, 8);
                    if app.pulse_shaping
                        [app.wave_i, ~, ~] = pulse_shaping_filter(app.coeffs_i, 8);
                        [app.wave_q, ~, ~] = pulse_shaping_filter(app.coeffs_q, 8);
                    end
                    plot(app.UIAxes, app.t, app.wave_i);
                    plot(app.UIAxes2, app.t, app.wave_q);
                case 16
                    [app.t, app.coeffs_i, app.coeffs_q, app.wave_i, app.wave_q] = NRZ_polar_QAM(app.demo_bit_seq, 16);
                    if app.pulse_shaping
                        [app.wave_i, ~, ~] = pulse_shaping_filter(app.coeffs_i, 16);
                        [app.wave_q, ~, ~] = pulse_shaping_filter(app.coeffs_q, 16);
                    end
                    plot(app.UIAxes, app.t, app.wave_i);
                    plot(app.UIAxes2, app.t, app.wave_q);
            end
        end

        % Value changed function: CodificaodelinhaDropDown_2
        function CodificaodelinhaDropDown_2ValueChanged(app, event)
            value = app.CodificaodelinhaDropDown_2.Value;
            switch value
                case 'NRZ Polar (BPSK)'
                    app.NRZ_levels = 1;
                case 'NRZ Polar (QPSK)'
                    app.NRZ_levels = 2;
                case 'NRZ 4-ário (16-QAM)'
                    app.NRZ_levels = 4;
                case 'NRZ 8-ário (64-QAM)'
                    app.NRZ_levels = 8;
                case 'NRZ 16-ário (256-QAM)'
                    app.NRZ_levels = 16;
            end
        end

        % Value changed function: PulseshapingFilterCheckBox_2
        function PulseshapingFilterCheckBox_2ValueChanged(app, event)
            app.pulse_shaping = app.PulseshapingFilterCheckBox_2.Value;
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            app.done_flag = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Coeficientespolinmio1EditFieldLabel = uilabel(app.Panel_2);
            app.Coeficientespolinmio1EditFieldLabel.Text = 'Coeficientes polinômio 1';
            app.Coeficientespolinmio1EditField = uieditfield(app.Panel_2, 'text');
            app.Coeficientespolinmio1EditField.Value = '11011';
            app.Coeficientespolinmio2EditFieldLabel = uilabel(app.Panel_2);
            app.Coeficientespolinmio2EditFieldLabel.Text = 'Coeficientes polinômio 2';
            app.Coeficientespolinmio2EditField = uieditfield(app.Panel_2, 'text');
            app.Coeficientespolinmio2EditField.Value = '10101';
            app.CodificaoconvolucionalSwitchLabel = uilabel(app.Panel_2);
            app.CodificaoconvolucionalSwitchLabel.Text = 'Codificação convolucional';
            app.CodificaoconvolucionalSwitch = uiswitch(app.Panel_2, 'slider');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Modulante Q')
            xlabel(app.UIAxes2, 'Amostra')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Modulante I')
            xlabel(app.UIAxes, 'Amostra')
            zlabel(app.UIAxes, 'Z')
            app.SairButton = uibutton(app.UIFigure, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.GeralsinalButton = uibutton(app.UIFigure, 'push');
            app.GeralsinalButton.ButtonPushedFcn = createCallbackFcn(app, @GeralsinalButtonPushed, true);
            app.GeralsinalButton.Text = 'Geral sinal';
            app.Panel_4 = uipanel(app.UIFigure);
            app.CodificaodelinhaDropDown_2Label = uilabel(app.Panel_4);
            app.CodificaodelinhaDropDown_2Label.Text = 'Codificação de linha';
            app.CodificaodelinhaDropDown_2 = uidropdown(app.Panel_4);
            app.CodificaodelinhaDropDown_2.Items = {'NRZ Polar (BPSK)', 'NRZ Polar (QPSK)', 'NRZ 4-ário (16-QAM)', 'NRZ 8-ário (64-QAM)', 'NRZ 16-ário (256-QAM)'};
            app.CodificaodelinhaDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CodificaodelinhaDropDown_2ValueChanged, true);
            app.CodificaodelinhaDropDown_2.Value = 'NRZ Polar (BPSK)';
            app.PulseshapingFilterCheckBox_2 = uicheckbox(app.Panel_4);
            app.PulseshapingFilterCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PulseshapingFilterCheckBox_2ValueChanged, true);
            app.PulseshapingFilterCheckBox_2.Enable = 'off';
            app.PulseshapingFilterCheckBox_2.Text = 'Pulse-shaping Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = banda_base

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end