classdef FonteDeDados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_random                    matlab.ui.container.Panel
        NmerodelinhasSpinner            matlab.ui.control.Spinner
        NmerodelinhasSpinnerLabel       matlab.ui.control.Label
        NmerodebitsporlinhaSpinner      matlab.ui.control.Spinner
        NmerodebitsporlinhaSpinnerLabel  matlab.ui.control.Label
        Panel_audio                     matlab.ui.container.Panel
        ERROLabel_2                     matlab.ui.control.Label
        AgravarLabel                    matlab.ui.control.Label
        OuvirButton                     matlab.ui.control.Button
        UtilizardefaultCheckBox         matlab.ui.control.CheckBox
        GravarButton                    matlab.ui.control.Button
        TempodegravaosegundosSpinner    matlab.ui.control.Spinner
        TempodegravaosegundosSpinnerLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        Panel_image                     matlab.ui.container.Panel
        ERROLabel                       matlab.ui.control.Label
        CarregarButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UtilizardefaultCheckBox_2       matlab.ui.control.CheckBox
        InsiraocaminhodaimagemLabel     matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        Panel_text                      matlab.ui.container.Panel
        InsiraseutextoASCIIExtendida8bitsLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        GerardadosbinriosButton         matlab.ui.control.Button
        FontededadosLabel               matlab.ui.control.Label
        SelecioneafontededadosDropDown  matlab.ui.control.DropDown
        SelecioneafontededadosDropDownLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        outputData (:,:) double = []          % Dados binários
        outputType (1,:) char = 'Sequência aleatória de bits' % Tipo de dados
        imgExtension (1,:) char = '.jpg'% Extensão da imagem
    end
    properties (Access = public)
        doneFlag = false;
    end
    
    methods (Access = public)
        function [bits, dataType] = getData(app)
            if isempty(app.outputData)
                bits = ASCII_encoder('Sem dados');
                dataType = 'Texto';
                return;
            end
            dataType = app.outputType;
            bits = app.outputData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelecioneafontededadosDropDown
        function SelecioneafontededadosDropDownValueChanged(app, event)
            app.outputType = app.SelecioneafontededadosDropDown.Value;
            switch app.outputType 
                case 'Sequência aleatória de bits'
                    app.Panel_random.Visible = "on";
                    app.Panel_random.Enable = "on";
                    app.Panel_text.Visible = "off";
                    app.Panel_text.Enable = "off";
                    app.Panel_image.Visible = "off";
                    app.Panel_image.Enable = "off";
                    app.Panel_audio.Visible = "off";
                    app.Panel_audio.Enable = "off";
                case 'Texto'
                    app.Panel_text.Visible = "on";
                    app.Panel_text.Enable = "on";
                    app.Panel_random.Visible = "off";
                    app.Panel_random.Enable = "off";
                    app.Panel_image.Visible = "off";
                    app.Panel_image.Enable = "off";
                    app.Panel_audio.Visible = "off";
                    app.Panel_audio.Enable = "off";
                case 'Imagem'
                    app.Panel_image.Visible = "on";
                    app.Panel_image.Enable = "on";
                    app.Panel_random.Visible = "off";
                    app.Panel_random.Enable = "off";
                    app.Panel_text.Visible = "off";
                    app.Panel_text.Enable = "off";
                    app.Panel_audio.Visible = "off";
                    app.Panel_audio.Enable = "off";
                case 'Áudio'
                    app.Panel_audio.Visible = "on";
                    app.Panel_audio.Enable = "on";
                    app.Panel_random.Visible = "off";
                    app.Panel_random.Enable = "off";
                    app.Panel_text.Visible = "off";
                    app.Panel_text.Enable = "off";
                    app.Panel_image.Visible = "off";
                    app.Panel_image.Enable = "off";
                otherwise
                    warndlg('Selecione uma opção!');
                    app.Panel_audio.Visible = "off";
                    app.Panel_audio.Enable = "off";
                    app.Panel_random.Visible = "off";
                    app.Panel_random.Enable = "off";
                    app.Panel_text.Visible = "off";
                    app.Panel_text.Enable = "off";
                    app.Panel_image.Visible = "off";
                    app.Panel_image.Enable = "off";
            end
        end

        % Button pushed function: GerardadosbinriosButton
        function GerardadosbinriosButtonPushed(app, event)
            basePath = fileparts(mfilename('fullpath'));
            try
                switch app.outputType
                    case 'Sequência aleatória de bits'
                            nLinhas = app.NmerodelinhasSpinner.Value;
                            nBits = app.NmerodebitsporlinhaSpinner.Value;
                            app.outputData = random_bitseq_generator(nLinhas, nBits);
                    case 'Texto'
                        txt = app.EditField.Value;
                        if isempty(txt)
                            txt = 'Insira um valor da proxima vez...';
                        end
                        app.outputData = ASCII_encoder(txt);
                    case 'Áudio'
                        if app.UtilizardefaultCheckBox.Value
                            filename = fullfile(basePath, 'srcs', 'default.wav');
                            time = 3;
                        else
                            filename = fullfile(basePath, 'srcs', 'temp.wav');
                            time = app.TempodegravaosegundosSpinner.Value;
                        end
                        app.outputData = audio_encoder(filename, time);
                    case 'Imagem'
                        if app.UtilizardefaultCheckBox_2.Value
                            filename = fullfile(basePath, 'srcs', 'default.jpg');
                        else
                            filename = fullfile(basePath, 'srcs', ['temp' app.imgExtension]);
                        end
                        [~, imgData] = image_encoder(filename);
                        app.outputData = imgData;
                end
                app.doneFlag = true;
            catch
                app.outputData = [];
                app.doneFlag = true;
            end
        end

        % Button pushed function: OuvirButton
        function OuvirButtonPushed(app, event)
            basePath = fileparts(mfilename('fullpath'));  % pasta onde o app/script está
            if app.UtilizardefaultCheckBox.Value
                filename = fullfile(basePath, 'srcs', 'default.wav');
                audio_listen(filename);
            else
                filename = fullfile(basePath, 'srcs', 'temp.wav');
                audio_listen(filename);
            end
        end

        % Button pushed function: GravarButton
        function GravarButtonPushed(app, event)
            try
                duration = app.TempodegravaosegundosSpinner.Value;
                app.AgravarLabel.Visible = "on";
                rawData = audio_recorder(duration);
                app.AgravarLabel.Visible = "off";
                plot(app.UIAxes, rawData);
                app.UtilizardefaultCheckBox.Enable = "on";
                app.ERROLabel_2.Visible = "off";
            catch
                app.ERROLabel_2.Visible = "on";
            end

        end

        % Button pushed function: CarregarButton
        function CarregarButtonPushed(app, event)
            try
                basePath = fileparts(mfilename('fullpath'));
                filename = app.EditField_2.Value;
                [~, ~, ext] = fileparts(filename);
                destination = fullfile(basePath, 'srcs', ['temp' ext]);
                copyfile(filename, destination);
                app.Image.ImageSource = destination;
                app.ERROLabel.Visible = "off";
                app.UtilizardefaultCheckBox_2.Enable = "on";
            catch
                app.ERROLabel.Visible = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelecioneafontededadosDropDownLabel = uilabel(app.UIFigure);
            app.SelecioneafontededadosDropDownLabel.Text = 'Selecione a fonte de dados';
            app.SelecioneafontededadosDropDown = uidropdown(app.UIFigure);
            app.SelecioneafontededadosDropDown.Items = {'Sequência aleatória de bits', 'Texto', 'Imagem', 'Áudio'};
            app.SelecioneafontededadosDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecioneafontededadosDropDownValueChanged, true);
            app.SelecioneafontededadosDropDown.Value = 'Sequência aleatória de bits';
            app.FontededadosLabel = uilabel(app.UIFigure);
            app.FontededadosLabel.Text = 'Fonte de dados';
            app.GerardadosbinriosButton = uibutton(app.UIFigure, 'push');
            app.GerardadosbinriosButton.ButtonPushedFcn = createCallbackFcn(app, @GerardadosbinriosButtonPushed, true);
            app.GerardadosbinriosButton.Text = 'Gerar dados binários';
            app.Panel_text = uipanel(app.UIFigure);
            app.Panel_text.Enable = 'off';
            app.Panel_text.Visible = 'off';
            app.EditField = uieditfield(app.Panel_text, 'text');
            app.InsiraseutextoASCIIExtendida8bitsLabel = uilabel(app.Panel_text);
            app.InsiraseutextoASCIIExtendida8bitsLabel.Text = {'Insira seu texto'; '(ASCII Extendida - 8 bits)'};
            app.Panel_image = uipanel(app.UIFigure);
            app.Panel_image.Enable = 'off';
            app.Panel_image.Visible = 'off';
            app.EditField_2 = uieditfield(app.Panel_image, 'text');
            app.InsiraocaminhodaimagemLabel = uilabel(app.Panel_image);
            app.InsiraocaminhodaimagemLabel.Text = 'Insira o caminho da imagem';
            app.UtilizardefaultCheckBox_2 = uicheckbox(app.Panel_image);
            app.UtilizardefaultCheckBox_2.Enable = 'off';
            app.UtilizardefaultCheckBox_2.Text = 'Utilizar default';
            app.UtilizardefaultCheckBox_2.Value = true;
            app.Image = uiimage(app.Panel_image);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'srcs', 'default.jpg');
            app.CarregarButton = uibutton(app.Panel_image, 'push');
            app.CarregarButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarButtonPushed, true);
            app.CarregarButton.Text = 'Carregar';
            app.ERROLabel = uilabel(app.Panel_image);
            app.ERROLabel.Visible = 'off';
            app.ERROLabel.Text = 'ERRO';
            app.Panel_audio = uipanel(app.UIFigure);
            app.Panel_audio.Enable = 'off';
            app.Panel_audio.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel_audio);
            title(app.UIAxes, 'Sinal')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.Panel_audio);
            app.Label.Text = '';
            app.TempodegravaosegundosSpinnerLabel = uilabel(app.Panel_audio);
            app.TempodegravaosegundosSpinnerLabel.Text = 'Tempo de gravação (segundos)';
            app.TempodegravaosegundosSpinner = uispinner(app.Panel_audio);
            app.TempodegravaosegundosSpinner.Step = 0.5;
            app.TempodegravaosegundosSpinner.Limits = [1 5];
            app.TempodegravaosegundosSpinner.Value = 1;
            app.GravarButton = uibutton(app.Panel_audio, 'push');
            app.GravarButton.ButtonPushedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.GravarButton.Text = 'Gravar';
            app.UtilizardefaultCheckBox = uicheckbox(app.Panel_audio);
            app.UtilizardefaultCheckBox.Enable = 'off';
            app.UtilizardefaultCheckBox.Text = 'Utilizar default';
            app.UtilizardefaultCheckBox.Value = true;
            app.OuvirButton = uibutton(app.Panel_audio, 'push');
            app.OuvirButton.ButtonPushedFcn = createCallbackFcn(app, @OuvirButtonPushed, true);
            app.OuvirButton.Text = 'Ouvir';
            app.AgravarLabel = uilabel(app.Panel_audio);
            app.AgravarLabel.Visible = 'off';
            app.AgravarLabel.Text = 'A gravar...';
            app.ERROLabel_2 = uilabel(app.Panel_audio);
            app.ERROLabel_2.Visible = 'off';
            app.ERROLabel_2.Text = 'ERRO';
            app.Panel_random = uipanel(app.UIFigure);
            app.NmerodebitsporlinhaSpinnerLabel = uilabel(app.Panel_random);
            app.NmerodebitsporlinhaSpinnerLabel.Text = 'Número de bits por linha';
            app.NmerodebitsporlinhaSpinner = uispinner(app.Panel_random);
            app.NmerodebitsporlinhaSpinner.Step = 10;
            app.NmerodebitsporlinhaSpinner.Limits = [100 1000];
            app.NmerodebitsporlinhaSpinner.Value = 100;
            app.NmerodelinhasSpinnerLabel = uilabel(app.Panel_random);
            app.NmerodelinhasSpinnerLabel.Text = 'Número de linhas';
            app.NmerodelinhasSpinner = uispinner(app.Panel_random);
            app.NmerodelinhasSpinner.Limits = [1 10000];
            app.NmerodelinhasSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FonteDeDados

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end